classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulateurdevehiculelectriqueUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ItineraireTab                   matlab.ui.container.Tab
        UIAxes_profil                   matlab.ui.control.UIAxes
        UIAxes_plan                     matlab.ui.control.UIAxes
        DistanceLabel                   matlab.ui.control.Label
        ChercheritinraireButton         matlab.ui.control.Button
        RechercheLabel                  matlab.ui.control.Label
        InformationssurlitinraireLabel  matlab.ui.control.Label
        DistancetotaleLabel             matlab.ui.control.Label
        DpartEditFieldLabel             matlab.ui.control.Label
        DepartEditField                 matlab.ui.control.EditField
        ArriveEditFieldLabel            matlab.ui.control.Label
        DestinationEditField            matlab.ui.control.EditField
        DeniveleLabel                   matlab.ui.control.Label
        DenivelePLabel                  matlab.ui.control.Label
        DeniveleNLabel                  matlab.ui.control.Label
        DeniveleLabel_2                 matlab.ui.control.Label
        DeniveleLabel_3                 matlab.ui.control.Label
        DeniveleTotLabel                matlab.ui.control.Label
        PointculminantLabel             matlab.ui.control.Label
        PointleplusbasLabel             matlab.ui.control.Label
        PointCulmiLabel                 matlab.ui.control.Label
        PointBasLabel                   matlab.ui.control.Label
        TunnelLabel                     matlab.ui.control.Label
        CorrectButton                   matlab.ui.control.Button
        VitesseTab                      matlab.ui.container.Tab
        UIAxes_VLimRoute                matlab.ui.control.UIAxes
        UIAxes_VLimCorr                 matlab.ui.control.UIAxes
        UIAxes_VLim                     matlab.ui.control.UIAxes
        VehiculeTab                     matlab.ui.container.Tab
        VhiculeDropDownLabel            matlab.ui.control.Label
        VhiculeDropDown                 matlab.ui.control.DropDown
        ChoixvhiculeLabel               matlab.ui.control.Label
        PassagersLabel                  matlab.ui.control.Label
        Adultes80kgSpinnerLabel         matlab.ui.control.Label
        Adultes80kgSpinner              matlab.ui.control.Spinner
        Enfants40kgSpinnerLabel         matlab.ui.control.Label
        Enfants40kgSpinner              matlab.ui.control.Spinner
        CalculerButton                  matlab.ui.control.Button
        PoidsvidekgLabel                matlab.ui.control.Label
        PoidsdespassagerskgLabel        matlab.ui.control.Label
        Surfacefrontalem2Label          matlab.ui.control.Label
        CoefficientdetraineLabel        matlab.ui.control.Label
        ForcedepesanteurLabel           matlab.ui.control.Label
        ForcearodynamiqueLabel          matlab.ui.control.Label
        CoefficientderoulementLabel     matlab.ui.control.Label
        ForcederoulementsLabel          matlab.ui.control.Label
        CapacitdebatteriekwhLabel       matlab.ui.control.Label
        RendementconvertisseurLabel     matlab.ui.control.Label
        VhiculeLabel                    matlab.ui.control.Label
        RendementmoteurLabel            matlab.ui.control.Label
        RayondelarouemLabel             matlab.ui.control.Label
        EditField_PoidsVide             matlab.ui.control.NumericEditField
        EditField_PoidsPassager         matlab.ui.control.NumericEditField
        EditField_Surface               matlab.ui.control.NumericEditField
        EditField_CoeffTrain            matlab.ui.control.NumericEditField
        EditField_CoeffRoul             matlab.ui.control.NumericEditField
        EditField_RayonRoue             matlab.ui.control.NumericEditField
        EditField_CapBatterie           matlab.ui.control.NumericEditField
        EditField_RendConv              matlab.ui.control.NumericEditField
        EditField_RendMot               matlab.ui.control.NumericEditField
        RapportderductionLabel          matlab.ui.control.Label
        EditField_RappReduc             matlab.ui.control.NumericEditField
        RendementrducteurLabel          matlab.ui.control.Label
        EditField_RendReduc             matlab.ui.control.NumericEditField
        Button_2                        matlab.ui.control.Button
        CouplemoteurmaxLabel            matlab.ui.control.Label
        EditField_CoupleMax             matlab.ui.control.NumericEditField
        HoraireTab                      matlab.ui.container.Tab
        UIAxes_Position                 matlab.ui.control.UIAxes
        UIAxes_Vitesse                  matlab.ui.control.UIAxes
        UIAxes_Acceleration             matlab.ui.control.UIAxes
        ConsommationTab                 matlab.ui.container.Tab
        UIAxes_Consommation             matlab.ui.control.UIAxes
        UIAxes_Autonomie                matlab.ui.control.UIAxes
        EnergieLabel                    matlab.ui.control.Label
        InformationsLabel               matlab.ui.control.Label
        EnergieLabel_2                  matlab.ui.control.Label
        DeniveleLabel_4                 matlab.ui.control.Label
        EnergieRecupLabel               matlab.ui.control.Label
        DeniveleLabel_5                 matlab.ui.control.Label
        EnergieConsLabel                matlab.ui.control.Label
        UIAxes_Energie                  matlab.ui.control.UIAxes
        DeniveleLabel_7                 matlab.ui.control.Label
        ConsommationLabel               matlab.ui.control.Label
        DeniveleLabel_8                 matlab.ui.control.Label
        AutonomieLabel                  matlab.ui.control.Label
        DistanceTotLabel                matlab.ui.control.Label
        DistancetotaleLabel_2           matlab.ui.control.Label
        DeniveleLabel_9                 matlab.ui.control.Label
        DureeLabel                      matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: ChercheritinraireButton
        function ChercheritinraireButtonPushed(app, event)
            
            %% Onglet Itinéraire
            
            depart=app.DepartEditField.Value;
            destination=app.DestinationEditField.Value;
            assignin('base','depart',depart);
            assignin('base','destination',destination);
            assignin('base','lissTunnel',false);
            %Lancer le script ImportationRoute
            run('ImportationRoute.m');
            
            %Afficher si il y a un tunnel détecté sur le parcours
            if isTunnel == true
                app.TunnelLabel.Visible = 'on';
                app.CorrectButton.Visible = 'on';
            else
                app.TunnelLabel.Visible = 'off';
                app.CorrectButton.Visible = 'off';
            end
            
            %Afficher les spécificités du parcours
            app.DistanceLabel.Text = num2str(sprintf('%.0f',eval('base','dist_tot')))+" [m]";
            app.DeniveleTotLabel.Text = num2str(sprintf('%.0f',eval('base','denTot')))+" [m]";
            app.DenivelePLabel.Text = num2str(sprintf('%.0f',eval('base','dp')))+" [m]";
            app.DeniveleNLabel.Text = num2str(sprintf('%.0f',eval('base','dn')))+" [m]";
            app.PointCulmiLabel.Text = num2str(sprintf('%.0f',eval('base','pc')))+" [m]";
            app.PointBasLabel.Text = num2str(sprintf('%.0f',eval('base','plpb')))+" [m]";
            
            %Afficher les graphes du plan et du profil de la route
            plot(app.UIAxes_profil,eval('base','c_dist'), eval('base','z1'))
            plot(app.UIAxes_plan,eval('base','y1'), eval('base','x1'))
            
            %%Onglet Vitesse
            
            %Lancer le script ImportationRoute
            run('LimitationVitesse.m');
            
            %Afficher les graphes
            plot(app.UIAxes_VLimRoute,eval('base','c_dist'),3.6* eval('base','vlim_ref'))
            plot(app.UIAxes_VLimCorr,eval('base','c_dist'),3.6* eval('base','vlim'))
            plot(app.UIAxes_VLim,eval('base','w_dist'),3.6* eval('base','vlim_c'))
            
            
        end

        % Value changed function: DestinationEditField
        function DestinationEditFieldValueChanged(app, event)
            destination = app.DestinationEditField.Value;
        end

        % Callback function
        function ArriveEditFieldValueChanging(app, event)
            
        end

        % Value changed function: DepartEditField
        function DepartEditFieldValueChanged(app, event)
            depart = app.DepartEditField.Value;
        end

        % Value changing function: DepartEditField
        function DepartEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            depart=app.DepartEditField.Value
            destination=app.DestinationEditField.Value
            assignin('base','depart',depart);
            assignin('base','destination',destination);
            %             assignin('base','lissTunnel',true);
            run('ImportationRoute.m');
            %             if eval('base','isTunnel') == true
            %                 app.TunnelLabel.Visible = 'on';
            %                 app.CorrectButton.Visible = 'on';
            %             else
            %                 app.TunnelLabel.Visible = 'off';
            %                 app.CorrectButton.Visible = 'off';
            %             end
            
            app.DistanceLabel.Text = num2str(sprintf('%.0f',eval('base','dist_tot')))+" [m]";
            app.DeniveleTotLabel.Text = num2str(sprintf('%.0f',eval('base','denTot2')))+" [m]";
            app.DenivelePLabel.Text = num2str(sprintf('%.0f',eval('base','dp2')))+" [m]";
            app.DeniveleNLabel.Text = num2str(sprintf('%.0f',eval('base','dn2')))+" [m]";
            app.PointCulmiLabel.Text = num2str(sprintf('%.0f',eval('base','pc2')))+" [m]";
            app.PointBasLabel.Text = num2str(sprintf('%.0f',eval('base','plpb2')))+" [m]";
            plot(app.UIAxes_profil,eval('base','c_dist'), eval('base','z1'),eval('base','c_dist'), eval('base','z3'))
            %plot(app.UIAxes_profil,eval('base','c_dist'), eval('base','z3'))
            plot(app.UIAxes_plan,eval('base','y1'), eval('base','x1'))
            % run('ImportationRoute.m');
            %Onglet Vitesse
            run('LimitationVitesse.m');
            plot(app.UIAxes_VLimRoute,eval('base','c_dist'),3.6* eval('base','vlim_ref'))
            plot(app.UIAxes_VLimCorr,eval('base','c_dist'),3.6* eval('base','vlim'))
            plot(app.UIAxes_VLim,eval('base','w_dist'),3.6* eval('base','vlim_c'))
            
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Value changed function: VhiculeDropDown
        function VhiculeDropDownValueChanged(app, event)
            value = app.VhiculeDropDown.Value;
            switch value
                case "Tesla Model S 100D"
                    app.Button_2.Icon=('Tesla2S.png');
                    id=1;
                    m= 2100;
                    s = 1;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.32995;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=85;
                    rho = 1.225;
                    g=9.81;
                    mlimit=660;
                case "Tesla Model 3"
                    app.Button_2.Icon=('Tesla3.png');
                    id=2;
                    m= 1800;
                    s = 1;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.32995;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=75;
                    mlimit=410;
                case "Renault Zoé"
                    app.Button_2.Icon=('RenaultZoe.png');
                    id=3;
                    m= 1400;
                    s = 1.5;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.29;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=22;
                    mlimit=220;
                case "HESS TrolleyBus"
                    id=4;
                    app.Button_2.Icon=('T.png');
                    m= 15000;
                    s = 6;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.4;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=22;
                    mlimit=660;
                case "Personnalisé"
                    app.Button_2.Icon=('PointInter.jpg');
                    id=5;
                    m=app.EditField_PoidsVide.Value;
                    s=app.EditField_Surface.Value;
                    cx=app.EditField_CoeffTrain.Value;
                    crr=app.EditField_CoeffRoul.Value;
                    rr=app.EditField_RayonRoue.Value;
                    r=app.EditField_RappReduc.Value;
                    nr=app.EditField_RendReduc.Value;
                    cb=app.EditField_CapBatterie.Value;
                    nc=app.EditField_RendConv.Value;
                    nm= app.EditField_RendMot.Value;
                    mlimit = app.EditField_CoupleMax.Value
                otherwise
                    id=1;
            end
            
            if id ~= 5
                app.EditField_PoidsVide.Editable = 'off'
                app.EditField_PoidsPassager.Editable = 'off'
                app.EditField_Surface.Editable = 'off'
                app.EditField_CoeffTrain.Editable = 'off'
                app.EditField_CoeffRoul.Editable = 'off'
                app.EditField_RayonRoue.Editable = 'off'
                app.EditField_CapBatterie.Editable = 'off'
                app.EditField_RendConv.Editable = 'off'
                app.EditField_RendMot.Editable = 'off'
                app.EditField_CoupleMax.Editable = 'off'
            else
                app.EditField_PoidsVide.Editable= 'on'
                app.EditField_PoidsPassager.Editable = 'off'
                app.EditField_Surface.Editable = 'on'
                app.EditField_CoeffTrain.Editable = 'on'
                app.EditField_CoeffRoul.Editable = 'on'
                app.EditField_RayonRoue.Editable = 'on'
                app.EditField_CapBatterie.Editable = 'on'
                app.EditField_RendConv.Editable = 'on'
                app.EditField_RendMot.Editable = 'on'
                app.EditField_CoupleMax.Editable = 'on'
            end
            
            app.EditField_PoidsVide.Value = m;
            app.EditField_Surface.Value = s;
            app.EditField_CoeffTrain.Value = cx;
            app.EditField_CoeffRoul.Value = crr;
            app.EditField_RayonRoue.Value = rr;
            app.EditField_RappReduc.Value=r;
            app.EditField_RendReduc.Value=nr;
            app.EditField_CapBatterie.Value = cb;
            app.EditField_RendConv.Value = nc;
            app.EditField_RendMot.Value = nm;
            app.EditField_CoupleMax.Value = mlimit;
            
        end

        % Value changed function: Adultes80kgSpinner
        function Adultes80kgSpinnerValueChanged(app, event)
            app.EditField_PoidsPassager.Value = app.Adultes80kgSpinner.Value*80 + app.Enfants40kgSpinner.Value*40;
        end

        % Value changed function: Enfants40kgSpinner
        function Enfants40kgSpinnerValueChanged(app, event)
            app.EditField_PoidsPassager.Value = app.Adultes80kgSpinner.Value*80 + app.Enfants40kgSpinner.Value*40;
        end

        % Size changed function: VehiculeTab
        function VehiculeTabSizeChanged(app, event)
            
        end

        % Button pushed function: CalculerButton
        function CalculerButtonPushed2(app, event)
            value = app.VhiculeDropDown.Value;
            rho = 1.225;
            g=9.81;
            switch value
                case "Tesla Model S 100D"
                    m= 2100;
                    m= m + app.EditField_PoidsPassager.Value;
                    s = 1;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.32995;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=85;
                    rho = 1.225;
                    g=9.81;
                    mlimit =660;
                case "Tesla Model 3"
                    m= 2000;
                    m= m + app.EditField_PoidsPassager.Value;
                    s = 1;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.32995;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=66;
                    mlimit=440;
                case "Renault Zoé"
                    m= 1468;
                    m= m + app.EditField_PoidsPassager.Value;
                    s = 1.5;%2.341157;
                    cx = 0.5;%0.24;
                    crr=0.015;
                    rr= 0.29;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=22;
                    mlimit=220;
                case "HESS TrolleyBus"
                    m= 6000;
                    m= m + app.EditField_PoidsPassager.Value;
                    s = 5;%2.341157;
                    cx = 0.9;%0.24;
                    crr=0.015;
                    rr= 0.40;%19pouce de rayon de jante
                    r=9.73;
                    nr=0.9;
                    nm=0.89;
                    nc=0.89;
                    cb=22;
                    mlimit=1000;
                case "Personnalisé"
                    m=app.EditField_PoidsVide.Value;
                    m= m + app.EditField_PoidsPassager.Value;
                    s=app.EditField_Surface.Value;
                    cx=app.EditField_CoeffTrain.Value;
                    crr=app.EditField_CoeffRoul.Value;
                    rr=app.EditField_RayonRoue.Value;
                    r=app.EditField_RappReduc.Value;
                    nr=app.EditField_RendReduc.Value;
                    cb=app.EditField_CapBatterie.Value;
                    nc=app.EditField_RendConv.Value;
                    nm= app.EditField_RendMot.Value;
                    mlimit=app.EditField_CoupleMax.Value;
                otherwise
                    
            end
                   
            %% Onglet Itinéraire
            depart=app.DepartEditField.Value;
            destination=app.DestinationEditField.Value;
            assignin('base','depart',depart);
            assignin('base','destination',destination);
            assignin('base','lissTunnel',false);
            
            %Lancer le script ImportationRoute
            run('ImportationRoute.m');
            
            %Lancer le script ImportationRoute
            run('LimitationVitesse.m');
            
            %Onglet Horaire
            options = simset('SrcWorkspace','current');
            simout = sim('ModeleVehicule_2_0', [],options);
            plot(app.UIAxes_Position,eval('base','position.time'), eval('base','position.data')/1000)
            plot(app.UIAxes_Vitesse,eval('base','vitesse.time'),eval('base','vitesse.data'))
            plot(app.UIAxes_Acceleration,eval('base','acceleration.time'),eval('base','acceleration.data'))
            %Onglet Consommation
            plot(app.UIAxes_Energie,eval('base','energie.time'),eval('base','energie.data'))
            plot(app.UIAxes_Consommation,eval('base','consommation.time'),eval('base','consommation.data'))
            plot(app.UIAxes_Autonomie,eval('base','autonomie.time'),eval('base','autonomie.data')/1000)
            app.EnergieLabel.Text = num2str(sprintf('%.1f',eval('base','energie.data(length(energie.data))')))+" [kWh]";
            app.AutonomieLabel.Text = num2str(sprintf('%.1f',eval('base','autonomie.data(length(autonomie.data))')/1000))+" [km]";
            app.ConsommationLabel.Text = num2str(sprintf('%.1f',eval('base','consommation.data(length(consommation.data))')))+" [kWh/100km]";
            run('Consommation.m');
            app.EnergieConsLabel.Text = num2str(sprintf('%.1f',eval('base','enerC')))+" [kWh]";
            app.EnergieRecupLabel.Text = num2str(sprintf('%.1f',eval('base','enerR')))+" [kWh]";
            app.DistanceTotLabel.Text = num2str(sprintf('%.1f',eval('base','dist_tot')/1000))+" [km]";
            time = eval('base','position.time(length(position.time))');
            min =  floor(time/60);
            sec = round(((time/60)-min)*60);
            app.DureeLabel.Text = num2str(min)+":"+num2str(sec)+" [min:sec]";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulateurdevehiculelectriqueUIFigure = uifigure;
            app.SimulateurdevehiculelectriqueUIFigure.Name = 'Simulateur de véhicule électrique';
            app.TabGroup = uitabgroup(app.SimulateurdevehiculelectriqueUIFigure);
            app.ItineraireTab = uitab(app.TabGroup);
            app.ItineraireTab.Title = 'Itinéraire';
            app.UIAxes_profil = uiaxes(app.ItineraireTab);
            title(app.UIAxes_profil, 'Profil de la route')
            xlabel(app.UIAxes_profil, 'Distance [m]')
            ylabel(app.UIAxes_profil, 'Altitude [m]')
            app.UIAxes_plan = uiaxes(app.ItineraireTab);
            title(app.UIAxes_plan, 'Plan de la route')
            xlabel(app.UIAxes_plan, 'Longitude')
            ylabel(app.UIAxes_plan, 'Latitude')
            app.DistanceLabel = uilabel(app.ItineraireTab);
            app.DistanceLabel.Text = '';
            app.ChercheritinraireButton = uibutton(app.ItineraireTab, 'push');
            app.ChercheritinraireButton.ButtonPushedFcn = createCallbackFcn(app, @ChercheritinraireButtonPushed, true);
            app.ChercheritinraireButton.Text = 'Chercher itinéraire';
            app.RechercheLabel = uilabel(app.ItineraireTab);
            app.RechercheLabel.Text = 'Recherche :';
            app.InformationssurlitinraireLabel = uilabel(app.ItineraireTab);
            app.InformationssurlitinraireLabel.Text = 'Informations sur l''itinéraire :';
            app.DistancetotaleLabel = uilabel(app.ItineraireTab);
            app.DistancetotaleLabel.Text = 'Distance totale :';
            app.DpartEditFieldLabel = uilabel(app.ItineraireTab);
            app.DpartEditFieldLabel.Text = 'Départ';
            app.DepartEditField = uieditfield(app.ItineraireTab, 'text');
            app.DepartEditField.ValueChangedFcn = createCallbackFcn(app, @DepartEditFieldValueChanged, true);
            app.DepartEditField.ValueChangingFcn = createCallbackFcn(app, @DepartEditFieldValueChanging, true);
            app.ArriveEditFieldLabel = uilabel(app.ItineraireTab);
            app.ArriveEditFieldLabel.Text = 'Arrivée';
            app.DestinationEditField = uieditfield(app.ItineraireTab, 'text');
            app.DestinationEditField.ValueChangedFcn = createCallbackFcn(app, @DestinationEditFieldValueChanged, true);
            app.DeniveleLabel = uilabel(app.ItineraireTab);
            app.DeniveleLabel.Text = 'Dénivelé + :';
            app.DenivelePLabel = uilabel(app.ItineraireTab);
            app.DenivelePLabel.Text = '';
            app.DeniveleNLabel = uilabel(app.ItineraireTab);
            app.DeniveleNLabel.Text = '';
            app.DeniveleLabel_2 = uilabel(app.ItineraireTab);
            app.DeniveleLabel_2.Text = 'Dénivelé - :';
            app.DeniveleLabel_3 = uilabel(app.ItineraireTab);
            app.DeniveleLabel_3.Text = 'Dénivelé total :';
            app.DeniveleTotLabel = uilabel(app.ItineraireTab);
            app.DeniveleTotLabel.Text = '';
            app.PointculminantLabel = uilabel(app.ItineraireTab);
            app.PointculminantLabel.Text = 'Point culminant :';
            app.PointleplusbasLabel = uilabel(app.ItineraireTab);
            app.PointleplusbasLabel.Text = 'Point le plus bas :';
            app.PointCulmiLabel = uilabel(app.ItineraireTab);
            app.PointCulmiLabel.Text = '';
            app.PointBasLabel = uilabel(app.ItineraireTab);
            app.PointBasLabel.Text = '';
            app.TunnelLabel = uilabel(app.ItineraireTab);
            app.TunnelLabel.Visible = 'off';
            app.TunnelLabel.Text = {'Attention, ce trajet contient un(des) tunnel(s) '; ''; ''; 'Corriger le tracé :'};
            app.CorrectButton = uibutton(app.ItineraireTab, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Visible = 'off';
            app.CorrectButton.Text = 'Corriger tracé';
            app.VitesseTab = uitab(app.TabGroup);
            app.VitesseTab.Title = 'Vitesse';
            app.UIAxes_VLimRoute = uiaxes(app.VitesseTab);
            title(app.UIAxes_VLimRoute, 'Limitation de vitesse sur la route')
            xlabel(app.UIAxes_VLimRoute, 'Distance [m]')
            ylabel(app.UIAxes_VLimRoute, 'Vitesse [km/h]')
            app.UIAxes_VLimRoute.YLim = [0 130];
            app.UIAxes_VLimRoute.YTick = [0 30 50 80 100 120];
            app.UIAxes_VLimCorr = uiaxes(app.VitesseTab);
            title(app.UIAxes_VLimCorr, 'Limitation de vitesse adaptée aux virages')
            xlabel(app.UIAxes_VLimCorr, 'Distance [m]')
            ylabel(app.UIAxes_VLimCorr, 'Vitesse [km/h]')
            app.UIAxes_VLimCorr.YLim = [0 130];
            app.UIAxes_VLimCorr.YTick = [0 30 50 80 100 120];
            app.UIAxes_VLim = uiaxes(app.VitesseTab);
            title(app.UIAxes_VLim, 'Limitation de vitesse améliorée ')
            xlabel(app.UIAxes_VLim, 'Distance [m]')
            ylabel(app.UIAxes_VLim, 'Vitesse [km/h]')
            app.UIAxes_VLim.YLim = [0 130];
            app.UIAxes_VLim.YTick = [0 30 50 80 100 120];
            app.VehiculeTab = uitab(app.TabGroup);
            app.VehiculeTab.SizeChangedFcn = createCallbackFcn(app, @VehiculeTabSizeChanged, true);
            app.VehiculeTab.Title = 'Véhicule';
            app.VhiculeDropDownLabel = uilabel(app.VehiculeTab);
            app.VhiculeDropDownLabel.Text = 'Véhicule :';
            app.VhiculeDropDown = uidropdown(app.VehiculeTab);
            app.VhiculeDropDown.Items = {'Tesla Model S 100D', 'Tesla Model 3', 'Renault Zoé', 'HESS TrolleyBus', 'Personnalisé'};
            app.VhiculeDropDown.ValueChangedFcn = createCallbackFcn(app, @VhiculeDropDownValueChanged, true);
            app.VhiculeDropDown.Value = 'Tesla Model S 100D';
            app.ChoixvhiculeLabel = uilabel(app.VehiculeTab);
            app.ChoixvhiculeLabel.Text = 'Choix véhicule :';
            app.PassagersLabel = uilabel(app.VehiculeTab);
            app.PassagersLabel.Text = 'Passagers :';
            app.Adultes80kgSpinnerLabel = uilabel(app.VehiculeTab);
            app.Adultes80kgSpinnerLabel.Text = 'Adulte(s) (80kg) :';
            app.Adultes80kgSpinner = uispinner(app.VehiculeTab);
            app.Adultes80kgSpinner.Limits = [1 Inf];
            app.Adultes80kgSpinner.ValueChangedFcn = createCallbackFcn(app, @Adultes80kgSpinnerValueChanged, true);
            app.Adultes80kgSpinner.Value = 1;
            app.Enfants40kgSpinnerLabel = uilabel(app.VehiculeTab);
            app.Enfants40kgSpinnerLabel.Text = 'Enfant(s) (40kg) :';
            app.Enfants40kgSpinner = uispinner(app.VehiculeTab);
            app.Enfants40kgSpinner.Limits = [0 Inf];
            app.Enfants40kgSpinner.ValueChangedFcn = createCallbackFcn(app, @Enfants40kgSpinnerValueChanged, true);
            app.CalculerButton = uibutton(app.VehiculeTab, 'push');
            app.CalculerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerButtonPushed2, true);
            app.CalculerButton.Text = 'Calculer';
            app.PoidsvidekgLabel = uilabel(app.VehiculeTab);
            app.PoidsvidekgLabel.Text = 'Poids à vide [kg] :';
            app.PoidsdespassagerskgLabel = uilabel(app.VehiculeTab);
            app.PoidsdespassagerskgLabel.Text = 'Poids des passagers [kg] :';
            app.Surfacefrontalem2Label = uilabel(app.VehiculeTab);
            app.Surfacefrontalem2Label.Text = 'Surface frontale [m^2] :';
            app.CoefficientdetraineLabel = uilabel(app.VehiculeTab);
            app.CoefficientdetraineLabel.Text = 'Coefficient de trainée [-] :';
            app.ForcedepesanteurLabel = uilabel(app.VehiculeTab);
            app.ForcedepesanteurLabel.Text = 'Force de pesanteur';
            app.ForcearodynamiqueLabel = uilabel(app.VehiculeTab);
            app.ForcearodynamiqueLabel.Text = 'Force aérodynamique';
            app.CoefficientderoulementLabel = uilabel(app.VehiculeTab);
            app.CoefficientderoulementLabel.Text = 'Coefficient de roulement [-] :';
            app.ForcederoulementsLabel = uilabel(app.VehiculeTab);
            app.ForcederoulementsLabel.Text = 'Force de roulements';
            app.CapacitdebatteriekwhLabel = uilabel(app.VehiculeTab);
            app.CapacitdebatteriekwhLabel.Text = 'Capacité de batterie [kwh] :';
            app.RendementconvertisseurLabel = uilabel(app.VehiculeTab);
            app.RendementconvertisseurLabel.Text = 'Rendement convertisseur [-] :';
            app.VhiculeLabel = uilabel(app.VehiculeTab);
            app.VhiculeLabel.Text = 'Véhicule';
            app.RendementmoteurLabel = uilabel(app.VehiculeTab);
            app.RendementmoteurLabel.Text = 'Rendement moteur [-] :';
            app.RayondelarouemLabel = uilabel(app.VehiculeTab);
            app.RayondelarouemLabel.Text = 'Rayon de la roue [m] :';
            app.EditField_PoidsVide = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_PoidsVide.Editable = 'off';
            app.EditField_PoidsVide.Value = 2100;
            app.EditField_PoidsPassager = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_PoidsPassager.Editable = 'off';
            app.EditField_PoidsPassager.Value = 80;
            app.EditField_Surface = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_Surface.Editable = 'off';
            app.EditField_Surface.Value = 1;
            app.EditField_CoeffTrain = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_CoeffTrain.Editable = 'off';
            app.EditField_CoeffTrain.Value = 0.5;
            app.EditField_CoeffRoul = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_CoeffRoul.Editable = 'off';
            app.EditField_CoeffRoul.Value = 0.015;
            app.EditField_RayonRoue = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_RayonRoue.Editable = 'off';
            app.EditField_RayonRoue.Value = 0.32995;
            app.EditField_CapBatterie = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_CapBatterie.Editable = 'off';
            app.EditField_CapBatterie.Value = 85;
            app.EditField_RendConv = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_RendConv.Editable = 'off';
            app.EditField_RendConv.Value = 0.89;
            app.EditField_RendMot = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_RendMot.Editable = 'off';
            app.EditField_RendMot.Value = 0.89;
            app.RapportderductionLabel = uilabel(app.VehiculeTab);
            app.RapportderductionLabel.Text = 'Rapport de réduction [-] :';
            app.EditField_RappReduc = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_RappReduc.Editable = 'off';
            app.EditField_RappReduc.Value = 0.89;
            app.RendementrducteurLabel = uilabel(app.VehiculeTab);
            app.RendementrducteurLabel.Text = 'Rendement réducteur [-] :';
            app.EditField_RendReduc = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_RendReduc.Editable = 'off';
            app.EditField_RendReduc.Value = 0.89;
            app.Button_2 = uibutton(app.VehiculeTab, 'push');
            app.Button_2.Icon = 'Tesla2S.png';
            app.Button_2.Text = '';
            app.CouplemoteurmaxLabel = uilabel(app.VehiculeTab);
            app.CouplemoteurmaxLabel.Text = 'Couple moteur max. [-] :';
            app.EditField_CoupleMax = uieditfield(app.VehiculeTab, 'numeric');
            app.EditField_CoupleMax.Editable = 'off';
            app.EditField_CoupleMax.Value = 660;
            app.HoraireTab = uitab(app.TabGroup);
            app.HoraireTab.Title = 'Horaire';
            app.UIAxes_Position = uiaxes(app.HoraireTab);
            title(app.UIAxes_Position, 'Position')
            xlabel(app.UIAxes_Position, 'Temps [s]')
            ylabel(app.UIAxes_Position, 'Position [km]')
            app.UIAxes_Vitesse = uiaxes(app.HoraireTab);
            title(app.UIAxes_Vitesse, 'Vitesse')
            xlabel(app.UIAxes_Vitesse, 'Temps [s]')
            ylabel(app.UIAxes_Vitesse, 'Vitesse [km/h]')
            app.UIAxes_Vitesse.YTick = [0 30 50 80 100 120];
            app.UIAxes_Acceleration = uiaxes(app.HoraireTab);
            title(app.UIAxes_Acceleration, 'Accélération')
            xlabel(app.UIAxes_Acceleration, 'Temps [s]')
            ylabel(app.UIAxes_Acceleration, 'Accélération [m/s^2]')
            app.UIAxes_Acceleration.YTick = [-4 -2 0 2 4];
            app.ConsommationTab = uitab(app.TabGroup);
            app.ConsommationTab.Title = 'Consommation';
            app.UIAxes_Consommation = uiaxes(app.ConsommationTab);
            title(app.UIAxes_Consommation, 'Consommation')
            xlabel(app.UIAxes_Consommation, 'Temps [s]')
            ylabel(app.UIAxes_Consommation, 'Conso. [kWh/100km]')
            app.UIAxes_Autonomie = uiaxes(app.ConsommationTab);
            title(app.UIAxes_Autonomie, 'Autonomie')
            xlabel(app.UIAxes_Autonomie, 'Temps [s]')
            ylabel(app.UIAxes_Autonomie, 'Autonomie [km]')
            app.EnergieLabel = uilabel(app.ConsommationTab);
            app.EnergieLabel.Text = '';
            app.InformationsLabel = uilabel(app.ConsommationTab);
            app.InformationsLabel.Text = 'Informations';
            app.EnergieLabel_2 = uilabel(app.ConsommationTab);
            app.EnergieLabel_2.Text = 'Energie :';
            app.DeniveleLabel_4 = uilabel(app.ConsommationTab);
            app.DeniveleLabel_4.Text = 'Energie récupérée :';
            app.EnergieRecupLabel = uilabel(app.ConsommationTab);
            app.EnergieRecupLabel.Text = '';
            app.DeniveleLabel_5 = uilabel(app.ConsommationTab);
            app.DeniveleLabel_5.Text = 'Energie consommée : ';
            app.EnergieConsLabel = uilabel(app.ConsommationTab);
            app.EnergieConsLabel.Text = '';
            app.UIAxes_Energie = uiaxes(app.ConsommationTab);
            title(app.UIAxes_Energie, 'Energie')
            xlabel(app.UIAxes_Energie, 'Temps [s]')
            ylabel(app.UIAxes_Energie, 'Energie [kWh]')
            app.DeniveleLabel_7 = uilabel(app.ConsommationTab);
            app.DeniveleLabel_7.Text = 'Consommation : ';
            app.ConsommationLabel = uilabel(app.ConsommationTab);
            app.ConsommationLabel.Text = '';
            app.DeniveleLabel_8 = uilabel(app.ConsommationTab);
            app.DeniveleLabel_8.Text = 'Autonomie :';
            app.AutonomieLabel = uilabel(app.ConsommationTab);
            app.AutonomieLabel.Text = '';
            app.DistanceTotLabel = uilabel(app.ConsommationTab);
            app.DistanceTotLabel.Text = '';
            app.DistancetotaleLabel_2 = uilabel(app.ConsommationTab);
            app.DistancetotaleLabel_2.Text = 'Distance totale :';
            app.DeniveleLabel_9 = uilabel(app.ConsommationTab);
            app.DeniveleLabel_9.Text = 'Durée :';
            app.DureeLabel = uilabel(app.ConsommationTab);
            app.DureeLabel.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulateurdevehiculelectriqueUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulateurdevehiculelectriqueUIFigure)
        end
    end
end